classdef Bisection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BisectionMethodLabel  matlab.ui.control.Label
        fxLabel               matlab.ui.control.Label
        fxEditField           matlab.ui.control.EditField
        Panel                 matlab.ui.container.Panel
        PrecisionLabel        matlab.ui.control.Label
        PrecisionEditField    matlab.ui.control.NumericEditField
        SolveButton           matlab.ui.control.Button
        bLabel                matlab.ui.control.Label
        bEditField            matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.NumericEditField
        SolutionLabel         matlab.ui.control.Label
        SolutionEditField     matlab.ui.control.NumericEditField
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
        ShowGraphButton       matlab.ui.control.Button
        BisectionGraphLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
     Fun
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            syms x;
            f=app.Fun;
            a=app.aEditField.Value;
            b=app.bEditField.Value;
            P=app.PrecisionEditField.Value;
            i=1;
            c=(b+a)/2;
            AbsError=abs(b-a);
           while AbsError>=P
                x=a;
                Func = eval(f);
                x=c;
                Funct = eval(f);
                
                if Func*Funct<0
                     
                     app.UITable.Data(i,:)=[i a b c AbsError];
                     b=c;
                   
                else
                      app.UITable.Data(i,:)=[i a b c AbsError];
                      a=c;
                                            
                end
                i=i+1;
                c=(b+a)/2;
                AbsError=abs(b-a);
                app.UITable.Data(i,:)=[i a b c AbsError];
           end
           
            app.SolutionEditField.Value=c;
          
          
        end

        % Value changed function: fxEditField
        function fxEditFieldValueChanged(app, event)
            app.Fun = app.fxEditField.Value;
            
        end

        % Button pushed function: ShowGraphButton
        function ShowGraphButtonPushed(app, event)
            syms x;
            f=app.Fun;
            a=app.aEditField.Value;
            b=app.bEditField.Value;
            P=app.PrecisionEditField.Value;
            i=1;
            %v1=[a:0.01:b];
           % x=v1;
            %y=eval(v1);
            cData=[];
            while abs(b-a)>=P
                c=(b+a)/2;
                x=a;
                Func = eval(f);  % Func=f(a);
                x=c;
                Funct = eval(f); %  Funct=f(c);
                 
                if Func*Funct<0
                     
                     b=c;
                   
                else
                     a=c;
                                            
                end
               cData(i) = c;
                i=i+1;
                
            end
             plot(app.UIAxes,cData,'r');    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BisectionMethodLabel = uilabel(app.UIFigure);
            app.BisectionMethodLabel.Text = 'Bisection Method :';
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x) =';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.ValueChangedFcn = createCallbackFcn(app, @fxEditFieldValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.PrecisionLabel = uilabel(app.Panel);
            app.PrecisionLabel.Text = '  Precision   =';
            app.PrecisionEditField = uieditfield(app.Panel, 'numeric');
            app.SolveButton = uibutton(app.Panel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.bLabel = uilabel(app.Panel);
            app.bLabel.Text = '     b        =';
            app.bEditField = uieditfield(app.Panel, 'numeric');
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = '     a        =';
            app.aEditField = uieditfield(app.Panel, 'numeric');
            app.SolutionLabel = uilabel(app.Panel);
            app.SolutionLabel.Text = 'Solution      :';
            app.SolutionEditField = uieditfield(app.Panel, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'iteration n'; 'an'; 'bn'; 'cn'; 'AbsError'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ShowGraphButton = uibutton(app.UIFigure, 'push');
            app.ShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGraphButtonPushed, true);
            app.ShowGraphButton.Text = 'Show Graph';
            app.BisectionGraphLabel = uilabel(app.UIFigure);
            app.BisectionGraphLabel.Text = 'Bisection Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bisection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end