classdef FixedPoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        gxLabel                 matlab.ui.control.Label
        gxEditField             matlab.ui.control.EditField
        FixedPointMethodLabel   matlab.ui.control.Label
        ParametresPanel         matlab.ui.container.Panel
        StartingPointLabel      matlab.ui.control.Label
        StartingPointEditField  matlab.ui.control.NumericEditField
        PrecisionLabel          matlab.ui.control.Label
        PrecisionEditField      matlab.ui.control.NumericEditField
        SolutionLabel           matlab.ui.control.Label
        SolutionEditField       matlab.ui.control.NumericEditField
        SolveButton             matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
        FixetPointGraphLabel    matlab.ui.control.Label
        ShowGraphButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
      Fun
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            syms x;
            g=app.Fun;
            P=app.PrecisionEditField.Value;
            xold=app.StartingPointEditField.Value;
            x=xold;
            Function=eval(g);
            xnew=Function;
            i=1;
            AbsError=abs(xold-xnew);
           
             while AbsError >= P
                 if i==1
                 
                     app.UITable.Data(i,:) = [i xnew AbsError];
                     xold=xnew;
                     i=i+1;
                 else
                      x=xold;
                     Function=eval(g);
                     xnew=Function;
                     AbsError = abs(xold-xnew);
                     app.UITable.Data(i,:) = [i xnew AbsError];
                     xold=xnew;
                     i=i+1;
                 end
                    
             
                pause(1); % to give some delays between each two iterations
             end
              app.SolutionEditField.Value=xnew;
                
        end

        % Value changed function: gxEditField
        function gxEditFieldValueChanged(app, event)
            app.Fun = app.gxEditField.Value;
            
        end

        % Value changed function: SolutionEditField
        function SolutionEditFieldValueChanged(app, event)
            value = app.SolutionEditField.Value;
            
        end

        % Button pushed function: ShowGraphButton
        function ShowGraphButtonPushed(app, event)
            syms x;
            g=app.Fun;
            P=app.PrecisionEditField.Value;
            xold=app.StartingPointEditField.Value;
            x=xold;
            Function=eval(g);
            xnew=Function;
            i=1;
            aData=[];
            AbsError=abs(xold-xnew);
           
             while AbsError >= P
                 if i==1
                     aData(i)=xold;
                 else
                     x=xold;
                     Function=eval(g);
                     xnew=Function;
                     AbsError = abs(xold-xnew);
                     xold=xnew;
                     aData(i)=xnew;
                 end
                  i=i+1;  
                pause(1); % to give some delays between each two iterations
             end
           plot(app.UIAxes,aData,'g ') ; 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.gxLabel = uilabel(app.UIFigure);
            app.gxLabel.Text = 'g(x) =';
            app.gxEditField = uieditfield(app.UIFigure, 'text');
            app.gxEditField.ValueChangedFcn = createCallbackFcn(app, @gxEditFieldValueChanged, true);
            app.FixedPointMethodLabel = uilabel(app.UIFigure);
            app.FixedPointMethodLabel.Text = 'Fixed Point Method :';
            app.ParametresPanel = uipanel(app.UIFigure);
            app.ParametresPanel.Title = 'Parametres';
            app.StartingPointLabel = uilabel(app.ParametresPanel);
            app.StartingPointLabel.Text = 'Starting Point :';
            app.StartingPointEditField = uieditfield(app.ParametresPanel, 'numeric');
            app.PrecisionLabel = uilabel(app.ParametresPanel);
            app.PrecisionLabel.Text = '  Precision        : ';
            app.PrecisionEditField = uieditfield(app.ParametresPanel, 'numeric');
            app.SolutionLabel = uilabel(app.ParametresPanel);
            app.SolutionLabel.Text = 'Solution    :';
            app.SolutionEditField = uieditfield(app.ParametresPanel, 'numeric');
            app.SolutionEditField.ValueChangedFcn = createCallbackFcn(app, @SolutionEditFieldValueChanged, true);
            app.SolveButton = uibutton(app.ParametresPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'n'; 'Xn'; 'AbsError'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FixetPointGraphLabel = uilabel(app.UIFigure);
            app.FixetPointGraphLabel.Text = 'Fixet Point Graph :';
            app.ShowGraphButton = uibutton(app.UIFigure, 'push');
            app.ShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGraphButtonPushed, true);
            app.ShowGraphButton.Text = 'Show Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FixedPoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end