classdef Newton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NewtonRaphsonMethodLabel     matlab.ui.control.Label
        fxLabel                      matlab.ui.control.Label
        fxEditField                  matlab.ui.control.EditField
        dfdxLabel                    matlab.ui.control.Label
        dfdxEditField                matlab.ui.control.EditField
        ParametresPanel              matlab.ui.container.Panel
        StartingPointEditFieldLabel  matlab.ui.control.Label
        StartingPointEditField       matlab.ui.control.NumericEditField
        PrecisionLabel               matlab.ui.control.Label
        PrecisionEditField           matlab.ui.control.NumericEditField
        SolutionEditFieldLabel       matlab.ui.control.Label
        SolutionEditField            matlab.ui.control.NumericEditField
        SolveButton                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        NewtonRaphsonGraphLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ShowGraphButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
     Fun % on dit qu'ona une fonction a insrere et etre lu
     
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: fxEditField
        function fxEditFieldValueChanged(app, event)
            app.Fun = app.fxEditField.Value; % voila l'emplacement ou on va insere une fonction
            
            
        end

        % Callback function
        function SolveButtonPushed(app, event)
            
        end

        % Value changed function: StartingPointEditField
        function StartingPointEditFieldValueChanged(app, event)
            
        end

        % Value changed function: PrecisionEditField
        function PrecisionEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function DrawGraphButtonPushed(app, event)
         

        end

        % Button pushed function: ShowGraphButton
        function ShowGraphButtonPushed(app, event)
            syms x;
            f=app.Fun;
            df=diff(eval(f),x); % la derivee de f par rapport a x
            app.dfdxEditField.Value=char(df);
            P=app.PrecisionEditField.Value;
            xold=app.StartingPointEditField.Value;
            x=xold;
            Function = eval(f);
            Derivative= eval(df) ;%subs(df,x,xold)
            xnew = xold - Function/Derivative ;
            i=1;
            %aData=[];
            bData=[];
            while abs(xold-xnew)>=P
                if i==1
                   x=xold;
                   Function = eval(f);
                   Derivative= eval(df) ;%subs(df,x,xold)
                   xnew = xold - Function/Derivative ;
                else
                    xold=xnew;
                    x=xold;
                    Function = eval(f);
                    Derivative= eval(df) ;%subs(df,x,xold)
                    xnew = xold - Function/Derivative ;
                end
               % aData(i)=xold;
                bData(i)=xold;
                AbsError = abs(xold-xnew);
                i=i+1;
            end 
            plot(app.UIAxes,bData,'g');
            

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed2(app, event)
            syms x;
            f=app.Fun;
            df=diff(eval(f),x); % la derivee de f par rapport a x
            app.dfdxEditField.Value=char(df);
            P=app.PrecisionEditField.Value;
            xold=app.StartingPointEditField.Value;
            x=xold;
            Function = eval(f);
            Derivative= eval(df) ;%subs(df,x,xold)
            xnew = xold - Function/Derivative ;
            i=1;
            while abs(xold-xnew)>=P
                if i==1
                   x=xold;
                   Function = eval(f);
                   Derivative= eval(df) ;%subs(df,x,xold)
                   xnew = xold - Function/Derivative ;
                   AbsError = abs(xold-xnew);
                   app.UITable.Data(i,:) = [i xold xnew AbsError];
                   i=i+1;
                else
                    xold=xnew;
                    x=xold;
                    Function = eval(f);
                    Derivative= eval(df) ;%subs(df,x,xold)
                    xnew = xold - Function/Derivative ;
                    AbsError = abs(xold-xnew);
                    app.UITable.Data(i,:) = [i xold xnew AbsError];
                    i=i+1;
                end
                pause(1); % to give some delays between each two iterations
      
            end
            app.SolutionEditField.Value=xold;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewtonRaphsonMethodLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonMethodLabel.Text = 'Newton-Raphson Method :';
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x)  =';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.ValueChangedFcn = createCallbackFcn(app, @fxEditFieldValueChanged, true);
            app.dfdxLabel = uilabel(app.UIFigure);
            app.dfdxLabel.Text = 'df/dx =';
            app.dfdxEditField = uieditfield(app.UIFigure, 'text');
            app.dfdxEditField.Editable = 'off';
            app.ParametresPanel = uipanel(app.UIFigure);
            app.ParametresPanel.Title = 'Parametres';
            app.StartingPointEditFieldLabel = uilabel(app.ParametresPanel);
            app.StartingPointEditFieldLabel.Text = 'Starting Point  : ';
            app.StartingPointEditField = uieditfield(app.ParametresPanel, 'numeric');
            app.StartingPointEditField.ValueChangedFcn = createCallbackFcn(app, @StartingPointEditFieldValueChanged, true);
            app.PrecisionLabel = uilabel(app.ParametresPanel);
            app.PrecisionLabel.Text = '    Precision         :';
            app.PrecisionEditField = uieditfield(app.ParametresPanel, 'numeric');
            app.PrecisionEditField.ValueChangedFcn = createCallbackFcn(app, @PrecisionEditFieldValueChanged, true);
            app.SolutionEditFieldLabel = uilabel(app.ParametresPanel);
            app.SolutionEditFieldLabel.Text = '    Solution         :';
            app.SolutionEditField = uieditfield(app.ParametresPanel, 'numeric');
            app.SolveButton = uibutton(app.ParametresPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed2, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'n'; 'Xold'; 'Xnew'; 'AbsError'};
            app.UITable.RowName = {};
            app.NewtonRaphsonGraphLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonGraphLabel.Text = 'Newton-Raphson Graph';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ShowGraphButton = uibutton(app.UIFigure, 'push');
            app.ShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGraphButtonPushed, true);
            app.ShowGraphButton.Text = 'Show Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Newton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end