classdef SecondPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SolveButton                     matlab.ui.control.Button
        DonnerlamatriceAEditFieldLabel  matlab.ui.control.Label
        DonnerlamatriceAEditField       matlab.ui.control.EditField
        DonnerlevecteurBEditFieldLabel  matlab.ui.control.Label
        DonnerlevecteurBEditField       matlab.ui.control.EditField
        LinearEquationsSolverLabel      matlab.ui.control.Label
        SolutionLabel                   matlab.ui.control.Label
        SolutionTextArea                matlab.ui.control.TextArea
        ShowGraphButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ChooseColorofGraphDropDownLabel  matlab.ui.control.Label
        ChooseColorofGraphDropDown      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            A=app.DonnerlamatriceAEditField.Value; 
            AA=str2num(A);
            [n,m]=size(AA);
            B=app.DonnerlevecteurBEditField.Value;
            BB=str2num(B);
            [p,q]=size(BB);
            if m==n
                if det(AA)==0
                    uicontrol(NonInversible);
                else
                    if p~=n || q~=1
                        uicontrol(Mal);
                    else
                      X=inv(AA)*BB;
                      XX=num2str(X);
                      app.SolutionTextArea.Value=cellstr(XX);  
                    end
                end
          
            else
            
                uicontrol(Carree);
            end
         
        end

        % Value changed function: DonnerlamatriceAEditField
        function DonnerlamatriceAEditFieldValueChanged(app, event)
            value = app.DonnerlamatriceAEditField.Value;
            
        end

        % Value changed function: DonnerlevecteurBEditField
        function DonnerlevecteurBEditFieldValueChanged(app, event)
            value = app.DonnerlevecteurBEditField.Value;
            
        end

        % Button pushed function: ShowGraphButton
        function ShowGraphButtonPushed(app, event)
           
            A=app.DonnerlamatriceAEditField.Value; 
            AA=str2num(A);
            [n,m]=size(AA);
            B=app.DonnerlevecteurBEditField.Value;
            BB=str2num(B);
            [p,q]=size(BB);
            if m==n
                if det(AA)==0
                    uicontrol(NonInversible);
                else
                    if p~=n || q~=1
                        uicontrol(Mal);
                    else
                      X=inv(AA)*BB;
                      Y=AA*X+BB;
                      c=app.ChooseColorofGraphDropDown.Value;
                      switch c
                          case'jaune'
                          c='y';
                          case'rouge'
                              c='r';
                          case'vert'
                              c='g';
                          
                          
                      end
                      
                      plot(app.UIAxes,X,Y,c);
                      
                       
                    end
                end
          
            else
            
                uicontrol(Carree);
            end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.DonnerlamatriceAEditFieldLabel = uilabel(app.UIFigure);
            app.DonnerlamatriceAEditFieldLabel.Text = 'Donner la matrice A :';
            app.DonnerlamatriceAEditField = uieditfield(app.UIFigure, 'text');
            app.DonnerlamatriceAEditField.ValueChangedFcn = createCallbackFcn(app, @DonnerlamatriceAEditFieldValueChanged, true);
            app.DonnerlevecteurBEditFieldLabel = uilabel(app.UIFigure);
            app.DonnerlevecteurBEditFieldLabel.Text = 'Donner le vecteur B :';
            app.DonnerlevecteurBEditField = uieditfield(app.UIFigure, 'text');
            app.DonnerlevecteurBEditField.ValueChangedFcn = createCallbackFcn(app, @DonnerlevecteurBEditFieldValueChanged, true);
            app.LinearEquationsSolverLabel = uilabel(app.UIFigure);
            app.LinearEquationsSolverLabel.Text = 'Linear Equations Solver :';
            app.SolutionLabel = uilabel(app.UIFigure);
            app.SolutionLabel.Text = '        Solution                     :';
            app.SolutionTextArea = uitextarea(app.UIFigure);
            app.ShowGraphButton = uibutton(app.UIFigure, 'push');
            app.ShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGraphButtonPushed, true);
            app.ShowGraphButton.Text = 'Show Graph';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X-Axis')
            ylabel(app.UIAxes, 'Y-Axis')
            app.ChooseColorofGraphDropDownLabel = uilabel(app.UIFigure);
            app.ChooseColorofGraphDropDownLabel.Text = 'Choose Color of Graph:';
            app.ChooseColorofGraphDropDown = uidropdown(app.UIFigure);
            app.ChooseColorofGraphDropDown.Items = {'jaune', 'rouge', 'vert'};
            app.ChooseColorofGraphDropDown.Value = 'jaune';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SecondPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end